classdef app1 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        TabGroup                        matlab.ui.container.TabGroup
        Tab                             matlab.ui.container.Tab
        AuthorMiracle24Version20201110Panel  matlab.ui.container.Panel
        ResultWindowPanel               matlab.ui.container.Panel
        UITable2                        matlab.ui.control.Table
        OptionWindowPanel               matlab.ui.container.Panel
        EditField                       matlab.ui.control.NumericEditField
        Label_12                        matlab.ui.control.Label
        SetButton                       matlab.ui.control.Button
        Panel                           matlab.ui.container.Panel
        UITable                         matlab.ui.control.Table
        Panel_2                         matlab.ui.container.Panel
        TabGroup2                       matlab.ui.container.TabGroup
        Tab_2                           matlab.ui.container.Tab
        Button_2                        matlab.ui.control.Button
        ParametersPanel_2               matlab.ui.container.Panel
        No2EditField_4                  matlab.ui.control.NumericEditField
        No2EditField_4Label             matlab.ui.control.Label
        No1EditField_4                  matlab.ui.control.NumericEditField
        No1EditField_4Label             matlab.ui.control.Label
        Label_10                        matlab.ui.control.Label
        ButtonGroup                     matlab.ui.container.ButtonGroup
        No1No2Button_2                  matlab.ui.control.RadioButton
        No1No2Button                    matlab.ui.control.RadioButton
        EditField_2                     matlab.ui.control.NumericEditField
        Label_11                        matlab.ui.control.Label
        Label_9                         matlab.ui.control.Label
        No2EditField_3                  matlab.ui.control.NumericEditField
        No2EditField_3Label             matlab.ui.control.Label
        No1EditField_3                  matlab.ui.control.NumericEditField
        No1EditField_3Label             matlab.ui.control.Label
        AvailableElementsButtonGroup    matlab.ui.container.ButtonGroup
        Image_6                         matlab.ui.control.Image
        Image2_2                        matlab.ui.control.Image
        Image_5                         matlab.ui.control.Image
        UsButton                        matlab.ui.control.RadioButton
        IsButton                        matlab.ui.control.RadioButton
        RButton                         matlab.ui.control.RadioButton
        Tab_3                           matlab.ui.container.Tab
        ParametersPanel                 matlab.ui.container.Panel
        ButtonGroup_3                   matlab.ui.container.ButtonGroup
        Button_6                        matlab.ui.control.RadioButton
        Button_5                        matlab.ui.control.RadioButton
        ButtonGroup_2                   matlab.ui.container.ButtonGroup
        Button_4                        matlab.ui.control.RadioButton
        Button_3                        matlab.ui.control.RadioButton
        KEditField                      matlab.ui.control.NumericEditField
        KLabel                          matlab.ui.control.Label
        Label_8                         matlab.ui.control.Label
        Label_7                         matlab.ui.control.Label
        No2EditField_2                  matlab.ui.control.NumericEditField
        No2Label_2                      matlab.ui.control.Label
        No1EditField_2                  matlab.ui.control.NumericEditField
        No1Label_2                      matlab.ui.control.Label
        No2EditField                    matlab.ui.control.NumericEditField
        No2Label                        matlab.ui.control.Label
        No1EditField                    matlab.ui.control.NumericEditField
        No1Label                        matlab.ui.control.Label
        AvailableElementsButtonGroup_2  matlab.ui.container.ButtonGroup
        Image                           matlab.ui.control.Image
        Image2                          matlab.ui.control.Image
        Image_3                         matlab.ui.control.Image
        Image_2                         matlab.ui.control.Image
        VCVSButton                      matlab.ui.control.RadioButton
        CCVSButton                      matlab.ui.control.RadioButton
        VCCSButton                      matlab.ui.control.RadioButton
        CCCSButton                      matlab.ui.control.RadioButton
        Button                          matlab.ui.control.Button
        Tips12setLabel                  matlab.ui.control.Label
        CalculateButton                 matlab.ui.control.Button
    end

    
    properties (Access = private)
        at; % elemet tabel
        at2; % elemet tabel 2
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            
        end

        % Button pushed function: SetButton
        function SetButtonPushed(app, event)
            app.at = app.UITable.Data;
            app.UITable.Data(:) = [];
            app.UITable2.Data(:) = [];
            app.at = app.UITable.Data;
        end

        % Button pushed function: Button_2
        function Button_2Pushed(app, event)
            No1 = app.No1EditField_4.Value;
            No2 = app.No2EditField_4.Value;
            if app.No1No2Button.Value
                k = 1;
            else
                k = -1;
            end
            if app.RButton.Value
                k = 1;
            end
            value = app.EditField_2.Value;
            switch app.AvailableElementsButtonGroup.SelectedObject.Text
                case '电 阻  R'
                    Name = 'R';
                case '电压源 Us'
                    Name = 'Us';
                otherwise
                    Name = 'Is';
            end
            app.UITable.Data = [app.at; {Name No1 No2 '' '' k*value}];
            app.at = app.UITable.Data;
        end

        % Button pushed function: Button
        function ButtonPushed(app, event)
            No1 = app.No1EditField.Value;
            No2 = app.No2EditField.Value;
            CNo1 = app.No1EditField_2.Value;
            CNo2 = app.No2EditField_2.Value;
            if app.Button_3.Value
                k = 1;
            else
                k = -1;
            end
            if app.Button_5.Value
                k = k*1;
            else
                k = k*-1;
            end
            value = app.KEditField.Value;
            Name = app.AvailableElementsButtonGroup_2.SelectedObject.Text;
            app.UITable.Data = [app.at; {Name No1 No2 CNo1 CNo2 k*value}];
            app.at = app.UITable.Data;
        end

        % Button pushed function: CalculateButton
        function CalculateButtonPushed(app, event)
            n = app.EditField.Value;
            r = size(app.at, 1);
            A = zeros(n*n,n*n);
            B = zeros(n*n,1);
            m = n+1;
            for i = 1:r
                Name = app.at{i,1};
                No1 = app.at{i,2};
                No2 = app.at{i,3};
                CNo1 = app.at{i,4};
                CNo2 = app.at{i,5};
                value = app.at{i,6};
                
                if strcmp(Name,'R')
                    A(No1,No1) = A(No1,No1)+1/value;
                    A(No2,No2) = A(No2,No2)+1/value;
                    A(No1,No2) = A(No1,No2)-1/value;
                    A(No2,No1) = A(No2,No1)-1/value;
                elseif strcmp(Name,'Is')
                    B(No1,1) = B(No1,1)-value;
                    B(No2,1) = B(No2,1)+value;
                elseif strcmp(Name,'Us')
                    A(No1,m) = A(No1,m)+1;
                    A(m,No1) = A(m,No1)+1;
                    A(No2,m) = A(No2,m)-1;
                    A(m,No2) = A(m,No2)-1;
                    B(m) = B(m)+value;
                    m = m+1;
                elseif strcmp(Name,'VCCS')
                    A(No1,CNo1) = A(No1,CNo1)+value;
                    A(No2,CNo2) = A(No2,CNo2)+value;
                    A(No1,CNo2) = A(No1,CNo2)-value;
                    A(No2,CNo1) = A(No2,CNo1)-value;
                elseif strcmp(Name,'VCVS')
                    A(No1,m) = A(No1,m)+1;
                    A(m,No1) = A(m,No1)+1;
                    A(No2,m) = A(No2,m)-1;
                    A(m,No2) = A(m,No2)-1;
                    A(m,CNo1) = A(m,CNo1)-value;
                    A(m,CNo2) = A(m,CNo2)+value;
                    m = m+1;
                elseif strcmp(Name,'CCCS')
                    A(No1,m) = A(No1,m)+value;
                    A(No2,m) = A(No2,m)-value;
                    m = m+1;
                else
                    A(No1,m) = A(No1,m)+1;
                    A(m,No1) = A(m,No1)+1;
                    A(No2,m) = A(No2,m)-1;
                    A(m,No2) = A(m,No2)-1;
                    A(m,m+1) = A(m,m+1)-value;
                    m = m+2;
                end
            end
            
            A(1,:) = 0;
            A(:,1) = 0;
            U = pinv(A)*B;
            if n
                app.UITable2.Data = [app.UITable2.Data; {1 0}];
            end
            for i = 2:n
                app.UITable2.Data = [app.UITable2.Data; {i U(i,1)}];
            end
            
            
        end

        % Callback function
        function PlotButtonPushed(app, event)
            U = app.UVEditField.Value;
            R = app.REditField.Value;
            C = app.CFEditField.Value;
            U0 = app.U0VEditField.Value;
            tao = R*C;
            x = 0:0.1:5*tao;
            y = (U0-U)*exp(-x/tao)+U;
            plot(app.UIAxes, x, y);
            set(app.UIAxes, 'XGrid',  'on', 'YGrid', 'on');
            xlabel(app.UIAxes, 't / s');
            ylabel(app.UIAxes, 'UC / V');
        end

        % Callback function
        function PlotButton_2Pushed(app, event)
            I = app.IAEditField.Value;
            R = app.REditField_3.Value;
            C = app.CFEditField_3.Value;
            L = app.LHEditField_2.Value;
            U0 = app.U0VEditField_3.Value;
            I0 = app.I0AEditField_2.Value;
            
            x = 0:0.1:100;
            y1=I - (exp(-(x*(L + (L*(- 4*C*R^2 + L))^(1/2)))/(2*C*L*R))*(L*I0 - I*L + I*(L*(- 4*C*R^2 + L))^(1/2) - I0*(L*(- 4*C*R^2 + L))^(1/2) + 2*C*R*U0))/(2*(L*(L - 4*C*R^2))^(1/2)) + (exp(-(x*(L - (L*(- 4*C*R^2 + L))^(1/2)))/(2*C*L*R))*(L*I0 - I*L - I*(L*(- 4*C*R^2 + L))^(1/2) + I0*(L*(- 4*C*R^2 + L))^(1/2) + 2*C*R*U0))/(2*(L*(L - 4*C*R^2))^(1/2));
            y3=(exp(-(x*(L + (L*(- 4*C*R^2 + L))^(1/2)))/(2*C*L*R))*(L + (L*(- 4*C*R^2 + L))^(1/2))*(L*I0 - I*L + I*(L*(- 4*C*R^2 + L))^(1/2) - I0*(L*(- 4*C*R^2 + L))^(1/2) + 2*C*R*U0))/(4*C*L*R*(L*(L - 4*C*R^2))^(1/2)) - (exp(-(x*(L - (L*(- 4*C*R^2 + L))^(1/2)))/(2*C*L*R))*(L - (L*(- 4*C*R^2 + L))^(1/2))*(L*I0 - I*L - I*(L*(- 4*C*R^2 + L))^(1/2) + I0*(L*(- 4*C*R^2 + L))^(1/2) + 2*C*R*U0))/(4*C*L*R*(L*(L - 4*C*R^2))^(1/2));
            y2 = L*y3;
            
            plot(app.UIAxes, x, y1, 'b', x, y2, 'r');
            set(app.UIAxes, 'XGrid', 'on', 'YGrid', 'on');
            xlabel(app.UIAxes, 't / s');
            ylabel(app.UIAxes, 'IL/A  UC/V');
            legend(app.UIAxes, 'IL-t', 'UC-t');
        end

        % Callback function
        function PlotButton_3Pushed(app, event)
            R = app.REditField_4.Value;
            C = app.CFEditField_4.Value;
            L = app.LHEditField_3.Value;
            U0 = app.U0VEditField_4.Value;
%             I0 = app.I0AEditField_3.Value;
            
            p1=-R/(2*L)+sqrt((R/2*L)^2-1/(C*L));
            p2=-R/(2*L)-sqrt((R/2*L)^2-1/(C*L));
            A1=p2*U0/(p2-p1);
            A2=-p1*U0/(p2-p1);
            
            if R > 2*sqrt(L/C)
                tm = log(p2/p1)/(p1-p2);
                t = 0:0.1:50*tm;
                y1 = A1*exp(p1*t)+A2*exp(p2*t);
                y2 = -U0*(exp(p1*t)-exp(p2*t))/(L*(p2-p1));
                title(app.UIAxes, '过阻尼电路');
            elseif R == 2*sqrt(L/C)
                t =0:0.1:50*R/(2*L);
                y1 = U0*(1+(R*t/(2*L))).*exp(-R*t/(2*L));
                y2 = (U0*t/L).*exp(-R*t/(2*L));
                title(app.UIAxes, '临界阻尼电路');
            else 
                w = sqrt(1/(C*L)-(R/(2*L))^2);
                w0 = sqrt(R/(2*L)^2+w^2);
                baita = atan(2*L*w/R);
                t = 0:0.1:100*baita;
                y1 = U0*w0/w*exp(-R*t/(2*L)).*sin(w*t+baita);
                y2 = U0/(w*L)*exp(-R*t/(2*L)).*sin(w*t);
                title(app.UIAxes, '欠阻尼电路');
            end
            plot(app.UIAxes, t, y1, 'b', t, y2, 'r');
            set(app.UIAxes, 'XGrid', 'on', 'YGrid', 'on');
            xlabel(app.UIAxes, 't / s');
            ylabel(app.UIAxes, 'UC/V  IL/A');
            legend(app.UIAxes, 'UC-t', 'IL-t')
        end

        % Callback function
        function CalculateButton_2Pushed(app, event)
            n = app.EditField_4.Value;
            tar1 = app.No1EditField_68.Value;
            tar2 = app.No2EditField_667.Value;
            
            r = size(app.at2, 1);
            A = zeros(n*n,n*n);
            B = zeros(n*n,1);
            m = n+1;
            for i = 1:r
                Name = app.at2{i,1};
                No1 = app.at2{i,2};
                No2 = app.at2{i,3};
                CNo1 = app.at2{i,4};
                CNo2 = app.at2{i,5};
                value = app.at2{i,6};
                
                if strcmp(Name,'R')
                    A(No1,No1) = A(No1,No1)+1/value;
                    A(No2,No2) = A(No2,No2)+1/value;
                    A(No1,No2) = A(No1,No2)-1/value;
                    A(No2,No1) = A(No2,No1)-1/value;
                elseif strcmp(Name,'Is')
                    B(No1,1) = B(No1,1)-value;
                    B(No2,1) = B(No2,1)+value;
                elseif strcmp(Name,'Us')
                    A(No1,m) = A(No1,m)+1;
                    A(m,No1) = A(m,No1)+1;
                    A(No2,m) = A(No2,m)-1;
                    A(m,No2) = A(m,No2)-1;
                    B(m) = B(m)+value;
                    m = m+1;
                elseif strcmp(Name,'VCCS')
                    A(No1,CNo1) = A(No1,CNo1)+value;
                    A(No2,CNo2) = A(No2,CNo2)+value;
                    A(No1,CNo2) = A(No1,CNo2)-value;
                    A(No2,CNo1) = A(No2,CNo1)-value;
                elseif strcmp(Name,'VCVS')
                    A(No1,m) = A(No1,m)+1;
                    A(m,No1) = A(m,No1)+1;
                    A(No2,m) = A(No2,m)-1;
                    A(m,No2) = A(m,No2)-1;
                    A(m,CNo1) = A(m,CNo1)-value;
                    A(m,CNo2) = A(m,CNo2)+value;
                    m = m+1;
                elseif strcmp(Name,'CCCS')
                    A(No1,m) = A(No1,m)+value;
                    A(No2,m) = A(No2,m)-value;
                    m = m+1;
                else
                    A(No1,m) = A(No1,m)+1;
                    A(m,No1) = A(m,No1)+1;
                    A(No2,m) = A(No2,m)-1;
                    A(m,No2) = A(m,No2)-1;
                    A(m,m+1) = A(m,m+1)-value;
                    m = m+2;
                end
            end
            
            A(1,:) = 0;
            A(:,1) = 0;
            U = pinv(A)*B;
            
            A(tar1,tar1) = A(tar1,tar1)+1/100;
            A(tar2,tar2) = A(tar2,tar2)+1/100;
            A(tar1,tar2) = A(tar1,tar2)-1/100;
            A(tar2,tar1) = A(tar2,tar1)-1/100;
            tU = pinv(A)*B;
            tI = (tU(tar1,1)-tU(tar2,1))/100;
            
            Ueq = U(tar1,1)-U(tar2,1);
            Req = Ueq/tI-100;
            Ieq = Ueq/Req;
            
            set(app.UeqEditField, abs(Ueq));
            set(app.ReqEditField, abs(Req));
            
            set(app.IeqEditField, abs(Ieq));
            set(app.ReqEditField_2, abs(Req));
        end

        % Callback function
        function Button_7Pushed(app, event)
            No1 = app.No1EditField_67.Value;
            No2 = app.No2EditField_666.Value;
            if app.No1No2Button_3.Value
                k = 1;
            else
                k = -1;
            end
            if app.RButton_2.Value
                k = 1;
            end
            value = app.EditField_4.Value;
            switch app.AvailableElementsButtonGroup_3.SelectedObject.Text
                case '电 阻  R'
                    Name = 'R';
                case '电压源 Us'
                    Name = 'Us';
                otherwise
                    Name = 'Is';
            end
            app.UITable_2.Data = [app.at2; {Name No1 No2 '' '' k*value}];
            app.at2 = app.UITable.Data;
        end

        % Callback function
        function SetButton_2Pushed(app, event)
            app.at2 = app.UITable_2.Data;
            app.UITable_2.Data(:) = [];
            app.at2 = app.UITable_2.Data;
        end

        % Callback function
        function Button_8Pushed(app, event)
            No1 = app.No1EditField_67.Value;
            No2 = app.No2EditField_67.Value;
            CNo1 = app.No1EditField_66.Value;
            CNo2 = app.No2EditField_66.Value;
            if app.Button_9.Value
                k = 1;
            else
                k = -1;
            end
            if app.Button_11.Value
                k = k*1;
            else
                k = k*-1;
            end
            value = app.KEditField_2.Value;
            Name = app.AvailableElementsButtonGroup_4.SelectedObject.Text;
            app.UITable_2.Data = [app.at2; {Name No1 No2 CNo1 CNo2 k*value}];
            app.at2 = app.UITable.Data;
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.TabGroup = uitabgroup(app.UIFigure);
            app.Tab = uitab(app.TabGroup);
            app.Tab.Title = '结点电压法';
            app.AuthorMiracle24Version20201110Panel = uipanel(app.Tab);
            app.AuthorMiracle24Version20201110Panel.Title = '电路程序设计实验——结点电压法           Author:Miracle24             Version: 2020.11.10';
            app.OptionWindowPanel = uipanel(app.AuthorMiracle24Version20201110Panel);
            app.OptionWindowPanel.Title = 'Option Window';
            app.CalculateButton = uibutton(app.OptionWindowPanel, 'push');
            app.CalculateButton.ButtonPushedFcn = createCallbackFcn(app, @CalculateButtonPushed, true);
            app.CalculateButton.Text = 'Calculate';
            app.Tips12setLabel = uilabel(app.OptionWindowPanel);
            app.Tips12setLabel.Text = {'Tips: 1.此处将结点定义为两端都有电路元件的点'; '      2.每次set都会重置输入'};
            app.Panel_2 = uipanel(app.OptionWindowPanel);
            app.Panel_2.Title = '可选元件';
            app.TabGroup2 = uitabgroup(app.Panel_2);
            app.Tab_2 = uitab(app.TabGroup2);
            app.Tab_2.Title = '基础原件';
            app.AvailableElementsButtonGroup = uibuttongroup(app.Tab_2);
            app.AvailableElementsButtonGroup.Title = 'Available Elements';
            app.RButton = uiradiobutton(app.AvailableElementsButtonGroup);
            app.RButton.Text = '电 阻  R';
            app.RButton.Value = true;
            app.IsButton = uiradiobutton(app.AvailableElementsButtonGroup);
            app.IsButton.Text = '电流源 Is';
            app.UsButton = uiradiobutton(app.AvailableElementsButtonGroup);
            app.UsButton.Text = '电压源 Us';
            app.Image_5 = uiimage(app.AvailableElementsButtonGroup);
            app.Image_5.ImageSource = 'Is.png';
            app.Image2_2 = uiimage(app.AvailableElementsButtonGroup);
            app.Image2_2.ImageSource = 'Us.png';
            app.Image_6 = uiimage(app.AvailableElementsButtonGroup);
            app.Image_6.ImageSource = 'R.png';
            app.ParametersPanel_2 = uipanel(app.Tab_2);
            app.ParametersPanel_2.Title = 'Parameters';
            app.No1EditField_3Label = uilabel(app.ParametersPanel_2);
            app.No1EditField_3Label.Text = 'No1：';
            app.No1EditField_3 = uieditfield(app.ParametersPanel_2, 'numeric');
            app.No2EditField_3Label = uilabel(app.ParametersPanel_2);
            app.No2EditField_3Label.Text = 'No2：';
            app.No2EditField_3 = uieditfield(app.ParametersPanel_2, 'numeric');
            app.Label_9 = uilabel(app.ParametersPanel_2);
            app.Label_9.Text = '接入结点编号';
            app.Label_11 = uilabel(app.ParametersPanel_2);
            app.Label_11.Text = '数        值';
            app.EditField_2 = uieditfield(app.ParametersPanel_2, 'numeric');
            app.ButtonGroup = uibuttongroup(app.ParametersPanel_2);
            app.No1No2Button = uiradiobutton(app.ButtonGroup);
            app.No1No2Button.Text = 'No1 → No2';
            app.No1No2Button.Value = true;
            app.No1No2Button_2 = uiradiobutton(app.ButtonGroup);
            app.No1No2Button_2.Text = 'No1 ← No2';
            app.Label_10 = uilabel(app.ParametersPanel_2);
            app.Label_10.Text = '方        向';
            app.No1EditField_4Label = uilabel(app.ParametersPanel_2);
            app.No1EditField_4Label.Text = 'No1:';
            app.No1EditField_4 = uieditfield(app.ParametersPanel_2, 'numeric');
            app.No1EditField_4.Limits = [0 Inf];
            app.No2EditField_4Label = uilabel(app.ParametersPanel_2);
            app.No2EditField_4Label.Text = 'No2:';
            app.No2EditField_4 = uieditfield(app.ParametersPanel_2, 'numeric');
            app.No2EditField_4.Limits = [0 Inf];
            app.Button_2 = uibutton(app.Tab_2, 'push');
            app.Button_2.ButtonPushedFcn = createCallbackFcn(app, @Button_2Pushed, true);
            app.Button_2.Text = {'添'; ''; '加'};
            app.Tab_3 = uitab(app.TabGroup2);
            app.Tab_3.Title = '受控源';
            app.Button = uibutton(app.Tab_3, 'push');
            app.Button.ButtonPushedFcn = createCallbackFcn(app, @ButtonPushed, true);
            app.Button.Text = {'添'; ''; '加'};
            app.AvailableElementsButtonGroup_2 = uibuttongroup(app.Tab_3);
            app.AvailableElementsButtonGroup_2.Title = 'Available Elements';
            app.CCCSButton = uiradiobutton(app.AvailableElementsButtonGroup_2);
            app.CCCSButton.Text = 'CCCS';
            app.CCCSButton.Value = true;
            app.VCCSButton = uiradiobutton(app.AvailableElementsButtonGroup_2);
            app.VCCSButton.Text = 'VCCS';
            app.CCVSButton = uiradiobutton(app.AvailableElementsButtonGroup_2);
            app.CCVSButton.Text = 'CCVS';
            app.VCVSButton = uiradiobutton(app.AvailableElementsButtonGroup_2);
            app.VCVSButton.Text = 'VCVS';
            app.Image_2 = uiimage(app.AvailableElementsButtonGroup_2);
            app.Image_2.ImageSource = 'VCVS.png';
            app.Image_3 = uiimage(app.AvailableElementsButtonGroup_2);
            app.Image_3.ImageSource = 'VCCS.png';
            app.Image2 = uiimage(app.AvailableElementsButtonGroup_2);
            app.Image2.ImageSource = 'CCVS.png';
            app.Image = uiimage(app.AvailableElementsButtonGroup_2);
            app.Image.ImageSource = 'CCCS.png';
            app.ParametersPanel = uipanel(app.Tab_3);
            app.ParametersPanel.Title = 'Parameters';
            app.No1Label = uilabel(app.ParametersPanel);
            app.No1Label.Text = 'No1:';
            app.No1EditField = uieditfield(app.ParametersPanel, 'numeric');
            app.No1EditField.Limits = [0 Inf];
            app.No2Label = uilabel(app.ParametersPanel);
            app.No2Label.Text = 'No2:';
            app.No2EditField = uieditfield(app.ParametersPanel, 'numeric');
            app.No2EditField.Limits = [0 Inf];
            app.No1Label_2 = uilabel(app.ParametersPanel);
            app.No1Label_2.Text = 'No1:';
            app.No1EditField_2 = uieditfield(app.ParametersPanel, 'numeric');
            app.No1EditField_2.Limits = [0 Inf];
            app.No2Label_2 = uilabel(app.ParametersPanel);
            app.No2Label_2.Text = 'No2:';
            app.No2EditField_2 = uieditfield(app.ParametersPanel, 'numeric');
            app.No2EditField_2.Limits = [0 Inf];
            app.Label_7 = uilabel(app.ParametersPanel);
            app.Label_7.Text = '接入结点';
            app.Label_8 = uilabel(app.ParametersPanel);
            app.Label_8.Text = '控制节点';
            app.KLabel = uilabel(app.ParametersPanel);
            app.KLabel.Text = '比 例 系 数  K: ';
            app.KEditField = uieditfield(app.ParametersPanel, 'numeric');
            app.KEditField.Limits = [0 Inf];
            app.ButtonGroup_2 = uibuttongroup(app.ParametersPanel);
            app.Button_3 = uiradiobutton(app.ButtonGroup_2);
            app.Button_3.Text = '1→2';
            app.Button_3.Value = true;
            app.Button_4 = uiradiobutton(app.ButtonGroup_2);
            app.Button_4.Text = '1←2';
            app.ButtonGroup_3 = uibuttongroup(app.ParametersPanel);
            app.Button_5 = uiradiobutton(app.ButtonGroup_3);
            app.Button_5.Text = '1→2';
            app.Button_5.Value = true;
            app.Button_6 = uiradiobutton(app.ButtonGroup_3);
            app.Button_6.Text = '1←2';
            app.Panel = uipanel(app.OptionWindowPanel);
            app.Panel.Title = '已添加元件';
            app.UITable = uitable(app.Panel);
            app.UITable.ColumnName = {'Type'; 'No1'; 'No2'; 'C-No1'; 'C-No2'; 'Value(1→2)'};
            app.UITable.RowName = {};
            app.UITable.ColumnSortable = [true false false false false false];
            app.UITable.ColumnEditable = true;
            app.SetButton = uibutton(app.OptionWindowPanel, 'push');
            app.SetButton.ButtonPushedFcn = createCallbackFcn(app, @SetButtonPushed, true);
            app.SetButton.Text = 'Set';
            app.Label_12 = uilabel(app.OptionWindowPanel);
            app.Label_12.Text = '结点总数：';
            app.EditField = uieditfield(app.OptionWindowPanel, 'numeric');
            app.ResultWindowPanel = uipanel(app.AuthorMiracle24Version20201110Panel);
            app.ResultWindowPanel.Title = 'Result Window';
            app.UITable2 = uitable(app.ResultWindowPanel);
            app.UITable2.ColumnName = {'结点编号'; '结点电压/V'};
            app.UITable2.RowName = {};
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = app1

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end