classdef app2 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        TabGroup                        matlab.ui.container.TabGroup
        Tab_3                           matlab.ui.container.Tab
        CopyrightMiracle20001110gmailcomVersion20201220Panel  matlab.ui.container.Panel
        ResultWindowPanel_3             matlab.ui.container.Panel
        Panel_4                         matlab.ui.container.Panel
        ReqEditField_2                  matlab.ui.control.NumericEditField
        ReqEditField_2Label             matlab.ui.control.Label
        IeqEditField                    matlab.ui.control.NumericEditField
        IeqEditFieldLabel               matlab.ui.control.Label
        Image6                          matlab.ui.control.Image
        Panel_3                         matlab.ui.container.Panel
        ReqEditField                    matlab.ui.control.NumericEditField
        ReqEditFieldLabel               matlab.ui.control.Label
        UeqEditField                    matlab.ui.control.NumericEditField
        UeqEditFieldLabel               matlab.ui.control.Label
        Image5                          matlab.ui.control.Image
        OptionWindowPanel_3             matlab.ui.container.Panel
        SetButton                       matlab.ui.control.Button
        TargetNode2EditField            matlab.ui.control.NumericEditField
        TargetNode2Label                matlab.ui.control.Label
        TargetNode1EditField            matlab.ui.control.NumericEditField
        TargetNode1Label                matlab.ui.control.Label
        EditField                       matlab.ui.control.NumericEditField
        Label                           matlab.ui.control.Label
        CalculateButton                 matlab.ui.control.Button
        Panel_2                         matlab.ui.container.Panel
        UITable                         matlab.ui.control.Table
        Panel                           matlab.ui.container.Panel
        TabGroup4                       matlab.ui.container.TabGroup
        Tab_4                           matlab.ui.container.Tab
        Button                          matlab.ui.control.Button
        ParametersPanel                 matlab.ui.container.Panel
        EditField_4                     matlab.ui.control.NumericEditField
        Label_4                         matlab.ui.control.Label
        EditField_3                     matlab.ui.control.NumericEditField
        Label_3                         matlab.ui.control.Label
        EditField_2                     matlab.ui.control.NumericEditField
        Label_2                         matlab.ui.control.Label
        AvailableElementsButtonGroup_2  matlab.ui.container.ButtonGroup
        Image3_2                        matlab.ui.control.Image
        Image2_2                        matlab.ui.control.Image
        Image_2                         matlab.ui.control.Image
        IsButton                        matlab.ui.control.RadioButton
        UsButton                        matlab.ui.control.RadioButton
        RButton                         matlab.ui.control.RadioButton
        Tab_5                           matlab.ui.container.Tab
        ParametersPanel_2               matlab.ui.container.Panel
        EditField_9                     matlab.ui.control.NumericEditField
        Label_6                         matlab.ui.control.Label
        EditField_8                     matlab.ui.control.NumericEditField
        EditField_8Label                matlab.ui.control.Label
        EditField_7                     matlab.ui.control.NumericEditField
        EditField_7Label                matlab.ui.control.Label
        EditField_6                     matlab.ui.control.NumericEditField
        EditField_6Label                matlab.ui.control.Label
        EditField_5                     matlab.ui.control.NumericEditField
        Label_5                         matlab.ui.control.Label
        Button_2                        matlab.ui.control.Button
        AvailableElementsButtonGroup    matlab.ui.container.ButtonGroup
        Image4                          matlab.ui.control.Image
        Image3                          matlab.ui.control.Image
        Image2                          matlab.ui.control.Image
        Image                           matlab.ui.control.Image
        Button4                         matlab.ui.control.RadioButton
        VCCSButton                      matlab.ui.control.RadioButton
        CCVSButton                      matlab.ui.control.RadioButton
        CCCSButton                      matlab.ui.control.RadioButton
        Tab_2                           matlab.ui.container.Tab
        CopyrightMiracle20001110gmailcomVersion20201230Panel  matlab.ui.container.Panel
        ResultWindowPanel_2             matlab.ui.container.Panel
        Panel_5                         matlab.ui.container.Panel
        OutputPanel                     matlab.ui.container.Panel
        IAEditField_5                   matlab.ui.control.NumericEditField
        IAEditField_5Label              matlab.ui.control.Label
        UVEditField_2                   matlab.ui.control.NumericEditField
        UVEditField_2Label              matlab.ui.control.Label
        InputPanel                      matlab.ui.container.Panel
        QueryButton                     matlab.ui.control.Button
        tsEditField                     matlab.ui.control.NumericEditField
        tsEditFieldLabel                matlab.ui.control.Label
        HistoryPanel                    matlab.ui.container.Panel
        UITable2                        matlab.ui.control.Table
        ClearButton                     matlab.ui.control.Button
        UIAxes                          matlab.ui.control.UIAxes
        OptionWindowPanel_2             matlab.ui.container.Panel
        id_tagEditField                 matlab.ui.control.NumericEditField
        id_tagEditFieldLabel            matlab.ui.control.Label
        type_tagEditField               matlab.ui.control.NumericEditField
        type_tagEditFieldLabel          matlab.ui.control.Label
        TabGroup3                       matlab.ui.container.TabGroup
        RCTab                           matlab.ui.container.Tab
        SetButton_2                     matlab.ui.control.Button
        EditField_12                    matlab.ui.control.NumericEditField
        EditField_12Label               matlab.ui.control.Label
        U0VEditField                    matlab.ui.control.NumericEditField
        U0VEditFieldLabel               matlab.ui.control.Label
        CFEditField                     matlab.ui.control.NumericEditField
        CFEditFieldLabel                matlab.ui.control.Label
        REditField                      matlab.ui.control.NumericEditField
        RLabel                          matlab.ui.control.Label
        UVEditField                     matlab.ui.control.NumericEditField
        UVLabel                         matlab.ui.control.Label
        PlotButton                      matlab.ui.control.Button
        RLTab_3                         matlab.ui.container.Tab
        SetButton_3                     matlab.ui.control.Button
        I0AEditField_4                  matlab.ui.control.NumericEditField
        I0AEditField_4Label             matlab.ui.control.Label
        LHEditField_6                   matlab.ui.control.NumericEditField
        LHEditField_6Label              matlab.ui.control.Label
        REditField_9                    matlab.ui.control.NumericEditField
        REditField_9Label               matlab.ui.control.Label
        IAEditField_4                   matlab.ui.control.NumericEditField
        IAEditField_4Label              matlab.ui.control.Label
        EditField_15                    matlab.ui.control.NumericEditField
        EditField_15Label               matlab.ui.control.Label
        PlotButton_8                    matlab.ui.control.Button
        RLCTab_2                        matlab.ui.container.Tab
        ButtonGroup_3                   matlab.ui.container.ButtonGroup
        Button_8                        matlab.ui.control.ToggleButton
        Button_7                        matlab.ui.control.ToggleButton
        SetButton_6                     matlab.ui.control.Button
        I0AEditField_2                  matlab.ui.control.NumericEditField
        I0AEditField_2Label             matlab.ui.control.Label
        U0VEditField_3                  matlab.ui.control.NumericEditField
        U0VEditField_3Label             matlab.ui.control.Label
        LHEditField_2                   matlab.ui.control.NumericEditField
        LHEditField_2Label              matlab.ui.control.Label
        CFEditField_3                   matlab.ui.control.NumericEditField
        CFEditField_3Label              matlab.ui.control.Label
        REditField_3                    matlab.ui.control.NumericEditField
        REditField_3Label               matlab.ui.control.Label
        UVEditField_3                   matlab.ui.control.NumericEditField
        UVEditField_3Label              matlab.ui.control.Label
        PlotButton_3                    matlab.ui.control.Button
    end

    
    properties (Access = private)
        at; % Description
        at2;
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: PlotButton_3
        function PlotButton_3Pushed(app, event)
            title(app.UIAxes, '');
            
            U = app.UVEditField_3.Value;
            R = app.REditField_3.Value;
            G = 1/R;
            I = U/R;
            C = app.CFEditField_3.Value;
            L = app.LHEditField_2.Value;
            U0 = app.U0VEditField_3.Value;
            I0 = app.I0AEditField_2.Value;
            
            if app.Button_7.Value
                p1 = -R/(2*L)+sqrt(R^2-4*L/C)/(2*L);
                p2 = -R/(2*L)-sqrt(R^2-4*L/C)/(2*L);
                if R > 2*sqrt(L/C)
                    A1 = (p2*(U0-U)-I0/C)/(p2-p1);
                    A2 = -(p1*(U0-U)-I0/C)/(p2-p1);
                    tm = (log((p2-p1)/(p2*U0))-2*log(10))/p1;
                    if U0 == 0
                        tm = (log((p2-p1)/p2)-2*log(10))/p1;
                    end
                    t = 0:tm/1e5:1.2*tm;
                    y1 = A1*exp(p1*t)+A2*exp(p2*t)+U;
                    y2 = -C*(p1*A1*exp(p1*t)+p2*A2*exp(p2*t));
                    title(app.UIAxes, '过阻尼电路');
                elseif R == 2*sqrt(L/C)
                    alph = 1/sqrt(L*C);
                    K1 = U0-U;
                    K2 = I0/C+alph*(U0-U);
                    tm = (2*L/R)*(abs(-wrightOmega(-log(U0)-5-pi*1i))-1);
                    if U0 == 0
                        tm = (2*L/R)*(abs(-wrightOmega(-5-pi*1i))-1);
                    end
                    t = 0:tm/1e5:1.2*tm;
                    y1 = (K1+K2*t).*exp(-alph*t)+U;
                    y2 = -C*(K2-alph*(K1+K2*t)).*exp(-alph*t);
                    title(app.UIAxes, '临界阻尼电路');
                else
                    alph = R/(2*L);
                    w0 = 1/sqrt(L*C);
                    w = sqrt(w0^2-alph^2);
                    K1 = U0-U;
                    K2 = (I0/C+(U0-U)*alph)/w;
                    K = sqrt(K1^2+K2^2);
                    theta = atan(K1/K2);
                    tm = (2*L/R)*(2*log(10)-log(w/(w0*U0)));
                    if U0 == 0
                        tm = (2*L/R)*(2*log(10)-log(w/w0));
                    end
                    t = 0:tm/1e5:1.2*tm;
                    y1 = K*exp(-alph*t).*sin(w*t+theta)+U;
                    y2 = -C*K*(-alph*exp(-alph*t).*sin(w*t+theta)+w*exp(-alph*t).*cos(w*t+theta));
                    title(app.UIAxes, '欠阻尼电路');
                end
            else
                p1 = -G/(2*C)+sqrt(G^2-4*C/L)/(2*C);
                p2 = -G/(2*C)-sqrt(G^2-4*C/L)/(2*C);
                if G > 2*sqrt(C/L)
                    A1 = (p2*(I0-I)-U0/L)/(p2-p1);
                    A2 = -(p1*(I0-I)-U0/L)/(p2-p1);
                    tm = (log((p2-p1)/(p2*I0))-2*log(10))/p1;
                    if U0 == 0
                        tm = (log((p2-p1)/p2)-2*log(10))/p1;
                    end
                    t = 0:tm/1e5:1.2*tm;
                    y2 = A1*exp(p1*t)+A2*exp(p2*t)+I;
                    y1 = -L*(p1*A1*exp(p1*t)+p2*A2*exp(p2*t));
                    title(app.UIAxes, '过阻尼电路');
                elseif G == 2*sqrt(C/L)
                    alph = 1/sqrt(C*L);
                    K1 = I0-I;
                    K2 = U0/L+alph*(I0-I);
                    tm = (2*C/G)*(abs(-wrightOmega(-log(I0)-5-pi*1i))-1);
                    if U0 == 0
                        tm = (2*C/G)*(abs(-wrightOmega(-5-pi*1i))-1);
                    end
                    t = 0:tm/1e5:1.2*tm;
                    y2 = (K1+K2*t).*exp(-alph*t)+I;
                    y1 = -L*(K2-alph*(K1+K2*t)).*exp(-alph*t);
                    title(app.UIAxes, '临界阻尼电路');
                else
                    alph = G/(2*C);
                    w0 = 1/sqrt(L*C);
                    w = sqrt(w0^2-alph^2);
                    K1 = I0-I;
                    K2 = (U0/L+(I0-I)*alph)/w;
                    K = sqrt(K1^2+K2^2);
                    theta = atan(K1/K2);
                    tm = (2*C/G)*(2*log(10)-log(w/(w0*I0)));
                    if U0 == 0
                        tm = (2*C/G)*(2*log(10)-log(w/w0));
                    end
                    t = 0:tm/1e5:1.2*tm;
                    y2 = K*exp(-alph*t).*sin(w*t+theta)+I;
                    y1 = -L*K*(-alph*exp(-alph*t).*sin(w*t+theta)+w*exp(-alph*t).*cos(w*t+theta));
                    title(app.UIAxes, '欠阻尼电路');
                end
            end

            plot(app.UIAxes, t, y1, 'b', t, y2, 'r');
            set(app.UIAxes, 'XGrid', 'on', 'YGrid', 'on');
            xlabel(app.UIAxes, 't / s');
            ylabel(app.UIAxes, 'UC/V  IL/A');
            legend(app.UIAxes, 'UC-t', 'IL-t');
            
            app.type_tagEditField.Value = 3;
        end

        % Button pushed function: PlotButton
        function PlotButtonPushed(app, event)
            title(app.UIAxes, '');
            
            U = app.UVEditField.Value;
            R = app.REditField.Value;
            C = app.CFEditField.Value;
            U0 = app.U0VEditField.Value;
            tao = R*C;
            if U0 == U
                mx = 0;
            else
                mx = tao*(2*log(10)+log(abs(U0-U)));
            end
            x = 0:mx/1e5:1.2*mx;
            y = (U0-U)*exp(-x/tao)+U;
            plot(app.UIAxes, x, y);
            set(app.UIAxes, 'XGrid',  'on', 'YGrid', 'on');
            xlabel(app.UIAxes, 't / s');
            ylabel(app.UIAxes, 'UC / V');
            
            app.EditField_12.Value = tao;
            app.type_tagEditField.Value = 1;
        end

        % Button pushed function: Button
        function ButtonPushed(app, event)
            No1 = app.EditField_2.Value;
            No2 = app.EditField_3.Value;
            value = app.EditField_4.Value;
            switch app.AvailableElementsButtonGroup_2.SelectedObject.Text
                case '电 阻  R'
                    Name = 'R';
                case '电压源 Us'
                    Name = 'Us';
                otherwise
                    Name = 'Is';
            end
            app.UITable.Data = [app.at; {Name No1 No2 '' '' value}];
            app.at = app.UITable.Data;
        end

        % Button pushed function: SetButton
        function SetButtonPushed(app, event)
            app.at = app.UITable.Data;
            app.UITable.Data(:) = [];
            app.at = app.UITable.Data;
        end

        % Button pushed function: Button_2
        function Button_2Pushed(app, event)
            No1 = app.EditField_5.Value;
            No2 = app.EditField_6.Value;
            CNo1 = app.EditField_7.Value;
            CNo2 = app.EditField_8.Value;
            value = app.EditField_9.Value;
            Name = app.AvailableElementsButtonGroup.SelectedObject.Text;
            app.UITable.Data = [app.at; {Name No1 No2 CNo1 CNo2 value}];
            app.at = app.UITable.Data;
        end

        % Button pushed function: CalculateButton
        function CalculateButtonPushed(app, event)
            n = app.EditField.Value;
            tar1 = app.TargetNode1EditField.Value;
            tar2 = app.TargetNode2EditField.Value;
            
            r = size(app.at, 1);
            A = zeros(n*n,n*n);
            B = zeros(n*n,1);
            m = n+1;
            for i = 1:r
                Name = app.at{i,1};
                No1 = app.at{i,2};
                No2 = app.at{i,3};
                CNo1 = app.at{i,4};
                CNo2 = app.at{i,5};
                value = app.at{i,6};
                
                if strcmp(Name,'R')
                    A(No1,No1) = A(No1,No1)+1/value;
                    A(No2,No2) = A(No2,No2)+1/value;
                    A(No1,No2) = A(No1,No2)-1/value;
                    A(No2,No1) = A(No2,No1)-1/value;
                elseif strcmp(Name,'Is')
                    B(No1,1) = B(No1,1)-value;
                    B(No2,1) = B(No2,1)+value;
                elseif strcmp(Name,'Us')
                    A(No1,m) = A(No1,m)+1;
                    A(m,No1) = A(m,No1)+1;
                    A(No2,m) = A(No2,m)-1;
                    A(m,No2) = A(m,No2)-1;
                    B(m) = B(m)+value;
                    m = m+1;
                elseif strcmp(Name,'VCCS')
                    A(No1,CNo1) = A(No1,CNo1)+value;
                    A(No2,CNo2) = A(No2,CNo2)+value;
                    A(No1,CNo2) = A(No1,CNo2)-value;
                    A(No2,CNo1) = A(No2,CNo1)-value;
                elseif strcmp(Name,'VCVS')
                    A(No1,m) = A(No1,m)+1;
                    A(m,No1) = A(m,No1)+1;
                    A(No2,m) = A(No2,m)-1;
                    A(m,No2) = A(m,No2)-1;
                    A(m,CNo1) = A(m,CNo1)-value;
                    A(m,CNo2) = A(m,CNo2)+value;
                    m = m+1;
                elseif strcmp(Name,'CCCS')
                    A(No1,m) = A(No1,m)+value;
                    A(No2,m) = A(No2,m)-value;
                    m = m+1;
                else
                    A(No1,m) = A(No1,m)+1;
                    A(m,No1) = A(m,No1)+1;
                    A(No2,m) = A(No2,m)-1;
                    A(m,No2) = A(m,No2)-1;
                    A(m,m+1) = A(m,m+1)-value;
                    m = m+2;
                end
            end
            
            A(1,:) = 0;
            A(:,1) = 0;
            U = pinv(A)*B;
            
            A(tar1,tar1) = A(tar1,tar1)+1/100;
            A(tar2,tar2) = A(tar2,tar2)+1/100;
            A(tar1,tar2) = A(tar1,tar2)-1/100;
            A(tar2,tar1) = A(tar2,tar1)-1/100;
            A(1,:) = 0;
            A(:,1) = 0;
            
            tU = pinv(A)*B;
            tI = (tU(tar1)-tU(tar2))/100;
            
            Ueq = U(tar1)-U(tar2);
            Req = Ueq/tI-100;
            Ieq = Ueq/Req;
            
            app.UeqEditField.Value = abs(Ueq);
            app.ReqEditField.Value = abs(Req);
            
            app.IeqEditField.Value = abs(Ieq);
            app.ReqEditField_2.Value = abs(Req);
        end

        % Button pushed function: PlotButton_8
        function PlotButton_8Pushed(app, event)
            title(app.UIAxes, '');
            
            I = app.IAEditField_4.Value;
            R = app.REditField_9.Value;
            L = app.LHEditField_6.Value;
            I0 = app.I0AEditField_4.Value;
            tao = L/R;
            
            if I0 == I
                mx = 0;
            else
                mx = tao*(2*log(10)+log(abs(I0-I)));
            end
            
            x = 0:mx/1e5:1.2*mx;
            y = (I0-I)*exp(-x/tao)+I;
            plot(app.UIAxes, x, y);
            
            set(app.UIAxes, 'XGrid',  'on', 'YGrid', 'on');
            xlabel(app.UIAxes, 't / s');
            ylabel(app.UIAxes, 'IL / A');
            
            app.EditField_15.Value = tao;
            app.type_tagEditField.Value = 2;
        end

        % Button pushed function: ClearButton
        function ClearButtonPushed(app, event)
            app.at2 = app.UITable2.Data;
            app.UITable2.Data(:) = [];
            app.at2 = app.UITable2.Data;
            app.id_tagEditField.Value = 0;
            delete(findobj(app.UIAxes, 'type', 'text'));
        end

        % Button pushed function: QueryButton
        function QueryButtonPushed(app, event)
            format short e
            
            t = app.tsEditField.Value;
            tp = app.type_tagEditField.Value;
            
            if tp == 1
                U = app.UVEditField.Value;
                R = app.REditField.Value;
                C = app.CFEditField.Value;
                U0 = app.U0VEditField.Value;
                tao = R*C;
                app.UVEditField_2.Value = (U0-U)*exp(-t/tao)+U;
                app.IAEditField_5.Value = 0;
            elseif tp == 2
                I = app.IAEditField_4.Value;
                R = app.REditField_9.Value;
                L = app.LHEditField_6.Value;
                I0 = app.I0AEditField_4.Value;
                tao = L/R;
                app.UVEditField_2.Value = 0;
                app.IAEditField_5.Value = (I0-I)*exp(-t/tao)+I;
            else
                U = app.UVEditField_3.Value;
                R = app.REditField_3.Value;
                G = 1/R;
                I = U/R;
                C = app.CFEditField_3.Value;
                L = app.LHEditField_2.Value;
                U0 = app.U0VEditField_3.Value;
                I0 = app.I0AEditField_2.Value;
                
                if app.Button_7.Value
                    p1 = -R/(2*L)+sqrt(R^2-4*L/C)/(2*L);
                    p2 = -R/(2*L)-sqrt(R^2-4*L/C)/(2*L);
                    if R > 2*sqrt(L/C)
                        A1 = (p2*(U0-U)-I0/C)/(p2-p1);
                        A2 = -(p1*(U0-U)-I0/C)/(p2-p1);
                        app.UVEditField_2.Value = A1*exp(p1*t)+A2*exp(p2*t)+U;
                        app.IAEditField_5.Value = -C*(p1*A1*exp(p1*t)+p2*A2*exp(p2*t));
                    elseif R == 2*sqrt(L/C)
                        alph = 1/sqrt(L*C);
                        K1 = U0-U;
                        K2 = I0/C+alph*(U0-U);
                        app.UVEditField_2.Value = (K1+K2*t)*exp(-alph*t)+U;
                        app.IAEditField_5.Value = -C*(K2-alph*(K1+K2*t))*exp(-alph*t);
                    else
                        alph = R/(2*L);
                        w0 = 1/sqrt(L*C);
                        w = sqrt(w0^2-alph^2);
                        K1 = U0-U;
                        K2 = (I0/C+(U0-U)*alph)/w;
                        K = sqrt(K1^2+K2^2);
                        theta = atan(K1/K2);
                        app.UVEditField_2.Value = K*exp(-alph*t)*sin(w*t+theta)+U;
                        app.IAEditField_5.Value = -C*K*(-alph*exp(-alph*t)*sin(w*t+theta)+w*exp(-alph*t)*cos(w*t+theta));
                    end
                else
                    p1 = -G/(2*C)+sqrt(G^2-4*C/L)/(2*C);
                    p2 = -G/(2*C)-sqrt(G^2-4*C/L)/(2*C);
                    if G > 2*sqrt(C/L)
                        A1 = (p2*(I0-I)-U0/L)/(p2-p1);
                        A2 = -(p1*(I0-I)-U0/L)/(p2-p1);
                        app.IAEditField_5.Value = A1*exp(p1*t)+A2*exp(p2*t)+I;
                        app.UVEditField_2.Value = -L*(p1*A1*exp(p1*t)+p2*A2*exp(p2*t));
                    elseif G == 2*sqrt(C/L)
                        alph = 1/sqrt(C*L);
                        K1 = I0-I;
                        K2 = U0/L+alph*(I0-I);
                        app.IAEditField_5.Value = (K1+K2*t)*exp(-alph*t)+I;
                        app.UVEditField_2.Value = -L*(K2-alph*(K1+K2*t))*exp(-alph*t);
                    else
                        alph = G/(2*C);
                        w0 = 1/sqrt(L*C);
                        w = sqrt(w0^2-alph^2);
                        K1 = I0-I;
                        K2 = (U0/L+(I0-I)*alph)/w;
                        K = sqrt(K1^2+K2^2);
                        theta = atan(K1/K2);
                        app.IAEditField_5.Value = K*exp(-alph*t)*sin(w*t+theta)+I;
                        app.UVEditField_2.Value = -L*K*(-alph*exp(-alph*t)*sin(w*t+theta)+w*exp(-alph*t)*cos(w*t+theta));
                    end
                end
            end
            
            if tp ~= 2
                text(app.UIAxes, t, app.UVEditField_2.Value, '*', 'color', 'black');
                text(app.UIAxes, t, app.UVEditField_2.Value, ['(',num2str(t),',',num2str(app.UVEditField_2.Value),')'], 'color', 'b');
            end
            
            if tp ~= 1
                text(app.UIAxes, t, app.IAEditField_5.Value, '*', 'color', 'black'); ...
                text(app.UIAxes, t, app.IAEditField_5.Value, ['(',num2str(t),',',num2str(app.IAEditField_5.Value),')'], 'color', 'r');
            end
            
            app.id_tagEditField.Value = app.id_tagEditField.Value+1;
            app.UITable2.Data = [app.at2; {num2str(round(app.id_tagEditField.Value)) num2str(app.tsEditField.Value) num2str(app.UVEditField_2.Value) num2str(app.IAEditField_5.Value)}];
            app.at2 = app.UITable2.Data;
        end

        % Button pushed function: SetButton_2
        function SetButton_2Pushed(app, event)
            app.UVEditField.Value = app.UeqEditField.Value;
            app.REditField.Value = app.ReqEditField.Value;
        end

        % Button pushed function: SetButton_3
        function SetButton_3Pushed(app, event)
            app.IAEditField_4.Value = app.IeqEditField.Value;
            app.REditField_9.Value = app.ReqEditField_2.Value;
        end

        % Button pushed function: SetButton_6
        function SetButton_6Pushed(app, event)
            app.UVEditField_3.Value = app.UeqEditField.Value;
            app.REditField_3.Value = app.ReqEditField_2.Value;
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.TabGroup = uitabgroup(app.UIFigure);
            app.Tab_3 = uitab(app.TabGroup);
            app.Tab_3.Title = '电路等效变换';
            app.CopyrightMiracle20001110gmailcomVersion20201220Panel = uipanel(app.Tab_3);
            app.CopyrightMiracle20001110gmailcomVersion20201220Panel.Title = '电路程序设计实验——电路等效变换          Copyright: Miracle20001110@gmail.com          Version: 2020.12.20';
            app.OptionWindowPanel_3 = uipanel(app.CopyrightMiracle20001110gmailcomVersion20201220Panel);
            app.OptionWindowPanel_3.Title = 'Option Window';
            app.Panel = uipanel(app.OptionWindowPanel_3);
            app.Panel.Title = '可选元件';
            app.TabGroup4 = uitabgroup(app.Panel);
            app.Tab_4 = uitab(app.TabGroup4);
            app.Tab_4.Title = '基础元件';
            app.AvailableElementsButtonGroup_2 = uibuttongroup(app.Tab_4);
            app.AvailableElementsButtonGroup_2.Title = 'Available Elements';
            app.RButton = uiradiobutton(app.AvailableElementsButtonGroup_2);
            app.RButton.Text = '电 阻  R';
            app.RButton.Value = true;
            app.UsButton = uiradiobutton(app.AvailableElementsButtonGroup_2);
            app.UsButton.Text = '电压源 Us';
            app.IsButton = uiradiobutton(app.AvailableElementsButtonGroup_2);
            app.IsButton.Text = '电流源 Is';
            app.Image_2 = uiimage(app.AvailableElementsButtonGroup_2);
            app.Image_2.ImageSource = 'R.png';
            app.Image2_2 = uiimage(app.AvailableElementsButtonGroup_2);
            app.Image2_2.ImageSource = 'Us.png';
            app.Image3_2 = uiimage(app.AvailableElementsButtonGroup_2);
            app.Image3_2.ImageSource = 'Is.png';
            app.ParametersPanel = uipanel(app.Tab_4);
            app.ParametersPanel.Title = 'Parameters';
            app.Label_2 = uilabel(app.ParametersPanel);
            app.Label_2.Text = '接入节点1';
            app.EditField_2 = uieditfield(app.ParametersPanel, 'numeric');
            app.Label_3 = uilabel(app.ParametersPanel);
            app.Label_3.Text = '接入节点2';
            app.EditField_3 = uieditfield(app.ParametersPanel, 'numeric');
            app.Label_4 = uilabel(app.ParametersPanel);
            app.Label_4.Text = '权         值';
            app.EditField_4 = uieditfield(app.ParametersPanel, 'numeric');
            app.Button = uibutton(app.Tab_4, 'push');
            app.Button.ButtonPushedFcn = createCallbackFcn(app, @ButtonPushed, true);
            app.Button.Text = {'添'; ''; ''; '加'};
            app.Tab_5 = uitab(app.TabGroup4);
            app.Tab_5.Title = '受控元件';
            app.AvailableElementsButtonGroup = uibuttongroup(app.Tab_5);
            app.AvailableElementsButtonGroup.Title = 'Available Elements';
            app.CCCSButton = uiradiobutton(app.AvailableElementsButtonGroup);
            app.CCCSButton.Text = 'CCCS';
            app.CCCSButton.Value = true;
            app.CCVSButton = uiradiobutton(app.AvailableElementsButtonGroup);
            app.CCVSButton.Text = 'CCVS';
            app.VCCSButton = uiradiobutton(app.AvailableElementsButtonGroup);
            app.VCCSButton.Text = 'VCCS';
            app.Button4 = uiradiobutton(app.AvailableElementsButtonGroup);
            app.Button4.Text = 'VCVS';
            app.Image = uiimage(app.AvailableElementsButtonGroup);
            app.Image.ImageSource = 'CCCS.png';
            app.Image2 = uiimage(app.AvailableElementsButtonGroup);
            app.Image2.ImageSource = 'CCVS.png';
            app.Image3 = uiimage(app.AvailableElementsButtonGroup);
            app.Image3.ImageSource = 'VCCS.png';
            app.Image4 = uiimage(app.AvailableElementsButtonGroup);
            app.Image4.ImageSource = 'VCVS.png';
            app.Button_2 = uibutton(app.Tab_5, 'push');
            app.Button_2.ButtonPushedFcn = createCallbackFcn(app, @Button_2Pushed, true);
            app.Button_2.Text = {'添'; ''; ''; '加'};
            app.ParametersPanel_2 = uipanel(app.Tab_5);
            app.ParametersPanel_2.Title = 'Parameters';
            app.Label_5 = uilabel(app.ParametersPanel_2);
            app.Label_5.Text = '接入节点1';
            app.EditField_5 = uieditfield(app.ParametersPanel_2, 'numeric');
            app.EditField_6Label = uilabel(app.ParametersPanel_2);
            app.EditField_6Label.Text = '接入节点2';
            app.EditField_6 = uieditfield(app.ParametersPanel_2, 'numeric');
            app.EditField_7Label = uilabel(app.ParametersPanel_2);
            app.EditField_7Label.Text = '控制节点1';
            app.EditField_7 = uieditfield(app.ParametersPanel_2, 'numeric');
            app.EditField_8Label = uilabel(app.ParametersPanel_2);
            app.EditField_8Label.Text = '控制节点2';
            app.EditField_8 = uieditfield(app.ParametersPanel_2, 'numeric');
            app.Label_6 = uilabel(app.ParametersPanel_2);
            app.Label_6.Text = '比例系数';
            app.EditField_9 = uieditfield(app.ParametersPanel_2, 'numeric');
            app.Panel_2 = uipanel(app.OptionWindowPanel_3);
            app.Panel_2.Title = '已添加元件';
            app.UITable = uitable(app.Panel_2);
            app.UITable.ColumnName = {'Type'; 'No1'; 'No2'; 'C-No1'; 'C-No2'; 'Value(1→2)'};
            app.UITable.RowName = {};
            app.UITable.ColumnSortable = [true true true true true false];
            app.UITable.ColumnEditable = [true true true true true false];
            app.CalculateButton = uibutton(app.OptionWindowPanel_3, 'push');
            app.CalculateButton.ButtonPushedFcn = createCallbackFcn(app, @CalculateButtonPushed, true);
            app.CalculateButton.Text = {'Calculate'; ''};
            app.Label = uilabel(app.OptionWindowPanel_3);
            app.Label.Text = '总结点数:';
            app.EditField = uieditfield(app.OptionWindowPanel_3, 'numeric');
            app.TargetNode1Label = uilabel(app.OptionWindowPanel_3);
            app.TargetNode1Label.Text = 'TargetNode1:';
            app.TargetNode1EditField = uieditfield(app.OptionWindowPanel_3, 'numeric');
            app.TargetNode2Label = uilabel(app.OptionWindowPanel_3);
            app.TargetNode2Label.Text = 'TargetNode2:';
            app.TargetNode2EditField = uieditfield(app.OptionWindowPanel_3, 'numeric');
            app.SetButton = uibutton(app.OptionWindowPanel_3, 'push');
            app.SetButton.ButtonPushedFcn = createCallbackFcn(app, @SetButtonPushed, true);
            app.SetButton.Text = 'Set';
            app.ResultWindowPanel_3 = uipanel(app.CopyrightMiracle20001110gmailcomVersion20201220Panel);
            app.ResultWindowPanel_3.Title = 'Result Window';
            app.Panel_3 = uipanel(app.ResultWindowPanel_3);
            app.Panel_3.Title = '戴维宁等效电路';
            app.Image5 = uiimage(app.Panel_3);
            app.Image5.ImageSource = 'dwn.png';
            app.UeqEditFieldLabel = uilabel(app.Panel_3);
            app.UeqEditFieldLabel.Text = 'Ueq';
            app.UeqEditField = uieditfield(app.Panel_3, 'numeric');
            app.UeqEditField.Editable = 'off';
            app.ReqEditFieldLabel = uilabel(app.Panel_3);
            app.ReqEditFieldLabel.Text = 'Req';
            app.ReqEditField = uieditfield(app.Panel_3, 'numeric');
            app.ReqEditField.Editable = 'off';
            app.Panel_4 = uipanel(app.ResultWindowPanel_3);
            app.Panel_4.Title = '诺顿等效电路';
            app.Image6 = uiimage(app.Panel_4);
            app.Image6.ImageSource = 'nd.png';
            app.IeqEditFieldLabel = uilabel(app.Panel_4);
            app.IeqEditFieldLabel.Text = 'Ieq';
            app.IeqEditField = uieditfield(app.Panel_4, 'numeric');
            app.IeqEditField.Editable = 'off';
            app.ReqEditField_2Label = uilabel(app.Panel_4);
            app.ReqEditField_2Label.Text = 'Req';
            app.ReqEditField_2 = uieditfield(app.Panel_4, 'numeric');
            app.ReqEditField_2.Editable = 'off';
            app.Tab_2 = uitab(app.TabGroup);
            app.Tab_2.Title = '线性动态电路分析';
            app.CopyrightMiracle20001110gmailcomVersion20201230Panel = uipanel(app.Tab_2);
            app.CopyrightMiracle20001110gmailcomVersion20201230Panel.Title = '电路程序设计实验——线性动态电路可视化分析           Copyright: Miracle20001110@gmail.com           Version: 2020.12.30';
            app.OptionWindowPanel_2 = uipanel(app.CopyrightMiracle20001110gmailcomVersion20201230Panel);
            app.OptionWindowPanel_2.Title = 'Option Window';
            app.TabGroup3 = uitabgroup(app.OptionWindowPanel_2);
            app.RCTab = uitab(app.TabGroup3);
            app.RCTab.Title = '一阶RC全响应';
            app.PlotButton = uibutton(app.RCTab, 'push');
            app.PlotButton.ButtonPushedFcn = createCallbackFcn(app, @PlotButtonPushed, true);
            app.PlotButton.Text = 'P l o t';
            app.UVLabel = uilabel(app.RCTab);
            app.UVLabel.Text = '电压源U(V)';
            app.UVEditField = uieditfield(app.RCTab, 'numeric');
            app.RLabel = uilabel(app.RCTab);
            app.RLabel.Text = '电阻R(Ω)';
            app.REditField = uieditfield(app.RCTab, 'numeric');
            app.CFEditFieldLabel = uilabel(app.RCTab);
            app.CFEditFieldLabel.Text = '电容C(F)';
            app.CFEditField = uieditfield(app.RCTab, 'numeric');
            app.U0VEditFieldLabel = uilabel(app.RCTab);
            app.U0VEditFieldLabel.Text = '电容初始电压U0(V)';
            app.U0VEditField = uieditfield(app.RCTab, 'numeric');
            app.EditField_12Label = uilabel(app.RCTab);
            app.EditField_12Label.Text = '时间常数';
            app.EditField_12 = uieditfield(app.RCTab, 'numeric');
            app.EditField_12.Editable = 'off';
            app.SetButton_2 = uibutton(app.RCTab, 'push');
            app.SetButton_2.ButtonPushedFcn = createCallbackFcn(app, @SetButton_2Pushed, true);
            app.SetButton_2.Text = 'Set';
            app.RLTab_3 = uitab(app.TabGroup3);
            app.RLTab_3.Title = '一阶RL全响应';
            app.PlotButton_8 = uibutton(app.RLTab_3, 'push');
            app.PlotButton_8.ButtonPushedFcn = createCallbackFcn(app, @PlotButton_8Pushed, true);
            app.PlotButton_8.Text = 'P l o t';
            app.EditField_15Label = uilabel(app.RLTab_3);
            app.EditField_15Label.Text = '时间常数';
            app.EditField_15 = uieditfield(app.RLTab_3, 'numeric');
            app.EditField_15.Editable = 'off';
            app.IAEditField_4Label = uilabel(app.RLTab_3);
            app.IAEditField_4Label.Text = '电流源I(A)';
            app.IAEditField_4 = uieditfield(app.RLTab_3, 'numeric');
            app.REditField_9Label = uilabel(app.RLTab_3);
            app.REditField_9Label.Text = '电阻R(Ω)';
            app.REditField_9 = uieditfield(app.RLTab_3, 'numeric');
            app.LHEditField_6Label = uilabel(app.RLTab_3);
            app.LHEditField_6Label.Text = '电感L(H)';
            app.LHEditField_6 = uieditfield(app.RLTab_3, 'numeric');
            app.I0AEditField_4Label = uilabel(app.RLTab_3);
            app.I0AEditField_4Label.Text = '电感初始电流I0(A)';
            app.I0AEditField_4 = uieditfield(app.RLTab_3, 'numeric');
            app.SetButton_3 = uibutton(app.RLTab_3, 'push');
            app.SetButton_3.ButtonPushedFcn = createCallbackFcn(app, @SetButton_3Pushed, true);
            app.SetButton_3.Text = 'Set';
            app.RLCTab_2 = uitab(app.TabGroup3);
            app.RLCTab_2.Title = '二阶RLC全响应';
            app.PlotButton_3 = uibutton(app.RLCTab_2, 'push');
            app.PlotButton_3.ButtonPushedFcn = createCallbackFcn(app, @PlotButton_3Pushed, true);
            app.PlotButton_3.Text = 'P l o t';
            app.UVEditField_3Label = uilabel(app.RLCTab_2);
            app.UVEditField_3Label.Text = '电压源U(V)';
            app.UVEditField_3 = uieditfield(app.RLCTab_2, 'numeric');
            app.REditField_3Label = uilabel(app.RLCTab_2);
            app.REditField_3Label.Text = '电阻R(Ω)';
            app.REditField_3 = uieditfield(app.RLCTab_2, 'numeric');
            app.CFEditField_3Label = uilabel(app.RLCTab_2);
            app.CFEditField_3Label.Text = '电容C(F)';
            app.CFEditField_3 = uieditfield(app.RLCTab_2, 'numeric');
            app.LHEditField_2Label = uilabel(app.RLCTab_2);
            app.LHEditField_2Label.Text = '电感L(H)';
            app.LHEditField_2 = uieditfield(app.RLCTab_2, 'numeric');
            app.U0VEditField_3Label = uilabel(app.RLCTab_2);
            app.U0VEditField_3Label.Text = '电容初始电压U0(V)';
            app.U0VEditField_3 = uieditfield(app.RLCTab_2, 'numeric');
            app.I0AEditField_2Label = uilabel(app.RLCTab_2);
            app.I0AEditField_2Label.Text = '电感初始电流I0(A)';
            app.I0AEditField_2 = uieditfield(app.RLCTab_2, 'numeric');
            app.SetButton_6 = uibutton(app.RLCTab_2, 'push');
            app.SetButton_6.ButtonPushedFcn = createCallbackFcn(app, @SetButton_6Pushed, true);
            app.SetButton_6.Text = 'Set';
            app.ButtonGroup_3 = uibuttongroup(app.RLCTab_2);
            app.Button_7 = uitogglebutton(app.ButtonGroup_3);
            app.Button_7.Text = '串联';
            app.Button_7.Value = true;
            app.Button_8 = uitogglebutton(app.ButtonGroup_3);
            app.Button_8.Text = '并联';
            app.type_tagEditFieldLabel = uilabel(app.OptionWindowPanel_2);
            app.type_tagEditFieldLabel.Visible = 'off';
            app.type_tagEditFieldLabel.Text = 'type_tag';
            app.type_tagEditField = uieditfield(app.OptionWindowPanel_2, 'numeric');
            app.type_tagEditField.Editable = 'off';
            app.type_tagEditField.Visible = 'off';
            app.id_tagEditFieldLabel = uilabel(app.OptionWindowPanel_2);
            app.id_tagEditFieldLabel.Visible = 'off';
            app.id_tagEditFieldLabel.Text = 'id_tag';
            app.id_tagEditField = uieditfield(app.OptionWindowPanel_2, 'numeric');
            app.id_tagEditField.ValueDisplayFormat = '%.0f';
            app.id_tagEditField.Editable = 'off';
            app.id_tagEditField.Visible = 'off';
            app.ResultWindowPanel_2 = uipanel(app.CopyrightMiracle20001110gmailcomVersion20201230Panel);
            app.ResultWindowPanel_2.Title = 'Result Window';
            app.UIAxes = uiaxes(app.ResultWindowPanel_2);
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            app.Panel_5 = uipanel(app.ResultWindowPanel_2);
            app.Panel_5.Title = '查询';
            app.HistoryPanel = uipanel(app.Panel_5);
            app.HistoryPanel.Title = 'History';
            app.ClearButton = uibutton(app.HistoryPanel, 'push');
            app.ClearButton.ButtonPushedFcn = createCallbackFcn(app, @ClearButtonPushed, true);
            app.ClearButton.Text = 'Clear';
            app.UITable2 = uitable(app.HistoryPanel);
            app.UITable2.ColumnName = {'id'; 't'; 'U'; 'I'};
            app.UITable2.RowName = {};
            app.InputPanel = uipanel(app.Panel_5);
            app.InputPanel.Title = 'Input';
            app.tsEditFieldLabel = uilabel(app.InputPanel);
            app.tsEditFieldLabel.Text = 't(s):';
            app.tsEditField = uieditfield(app.InputPanel, 'numeric');
            app.QueryButton = uibutton(app.InputPanel, 'push');
            app.QueryButton.ButtonPushedFcn = createCallbackFcn(app, @QueryButtonPushed, true);
            app.QueryButton.Text = {'Query'; ''};
            app.OutputPanel = uipanel(app.Panel_5);
            app.OutputPanel.Title = 'Output';
            app.UVEditField_2Label = uilabel(app.OutputPanel);
            app.UVEditField_2Label.Text = 'U(V):';
            app.UVEditField_2 = uieditfield(app.OutputPanel, 'numeric');
            app.UVEditField_2.Editable = 'off';
            app.IAEditField_5Label = uilabel(app.OutputPanel);
            app.IAEditField_5Label.Text = 'I(A):';
            app.IAEditField_5 = uieditfield(app.OutputPanel, 'numeric');
            app.IAEditField_5.Editable = 'off';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = app2

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end